classdef Clicky_GUI_For_Chrissi < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        SinFitPlot                    matlab.ui.control.UIAxes
        EquiphotonFluxPlot            matlab.ui.control.UIAxes
        PowerAtEquiphotonFlux         matlab.ui.control.UIAxes
        DataTable                     matlab.ui.control.Table
        CommandValueSliderLabel       matlab.ui.control.Label
        CommandValueSlider            matlab.ui.control.Slider
        WavelengthSliderLabel         matlab.ui.control.Label
        WavelengthSlider              matlab.ui.control.Slider
        FilenameEditFieldLabel        matlab.ui.control.Label
        FilenameEditField             matlab.ui.control.EditField
        CommandVoltageLabel           matlab.ui.control.Label
        PhotonsPerPulseLabel_2        matlab.ui.control.Label
        WavelengthLabel               matlab.ui.control.Label
        PhotonsPerPulseLabel          matlab.ui.control.Label
        PowerAtThisCommandLabel       matlab.ui.control.Label
        PoweratthisCommandValueLabel  matlab.ui.control.Label
    end

    
    methods (Access = private)
        
        function results = updatePlots(app)
            wavelength = app.WavelengthSlider.Value;
            CMD = app.CommandValueSlider.Value;
            
            [photon_flux_at_wavelength, equi_photon_cmd_value, unique_wavelengths, milliwatts_at_command_at_wavelength, filename] = Compute_CMD_Values_Func(wavelength, CMD, app.SinFitPlot, app.EquiphotonFluxPlot, app.PowerAtEquiphotonFlux);
            
            app.DataTable.Data = [cellstr(num2str(unique_wavelengths, '%.0f')), cellstr(num2str(100*(equi_photon_cmd_value), '%.1f')), cellstr(num2str(milliwatts_at_command_at_wavelength, '%.0f'))];
            app.PhotonsPerPulseLabel.Text = num2str(photon_flux_at_wavelength, 4);
            app.PowerAtThisCommandLabel.Text = [num2str(round(milliwatts_at_command_at_wavelength(unique_wavelengths == wavelength))), ' mW'];
            
            app.FilenameEditField.Value = filename;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: CommandValueSlider
        function CommandValueSliderValueChanged(app, event)
            app.CommandValueSlider.Value = round(app.CommandValueSlider.Value, 3);
            app.CommandVoltageLabel.Text = [num2str(app.CommandValueSlider.Value), ' V'];
            app.updatePlots();
        end

        % Value changed function: WavelengthSlider
        function WavelengthSliderValueChanged(app, event)
            app.WavelengthSlider.Value = round(app.WavelengthSlider.Value/10)*10;
            app.WavelengthLabel.Text = [num2str(app.WavelengthSlider.Value), ' nm'];
            app.updatePlots();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.SinFitPlot = uiaxes(app.UIFigure);
            title(app.SinFitPlot, 'Sine Fit Plot')
            xlabel(app.SinFitPlot, '')
            ylabel(app.SinFitPlot, 'Photon Count')
            app.EquiphotonFluxPlot = uiaxes(app.UIFigure);
            title(app.EquiphotonFluxPlot, 'Equiphoton Flux')
            xlabel(app.EquiphotonFluxPlot, '')
            ylabel(app.EquiphotonFluxPlot, 'Command Value')
            app.EquiphotonFluxPlot.XLim = [750 1080];
            app.PowerAtEquiphotonFlux = uiaxes(app.UIFigure);
            title(app.PowerAtEquiphotonFlux, 'Power at Equiphoton Flux')
            xlabel(app.PowerAtEquiphotonFlux, '')
            ylabel(app.PowerAtEquiphotonFlux, 'Power (mW)')
            app.PowerAtEquiphotonFlux.XLim = [750 1080];
            app.DataTable = uitable(app.UIFigure);
            app.DataTable.ColumnName = {'Wavelength'; 'Command Value (%)'; 'Power (mW)'};
            app.DataTable.RowName = {};
            app.CommandValueSliderLabel = uilabel(app.UIFigure);
            app.CommandValueSliderLabel.Text = 'Command Value: ';
            app.CommandValueSlider = uislider(app.UIFigure);
            app.CommandValueSlider.Limits = [0 1];
            app.CommandValueSlider.ValueChangedFcn = createCallbackFcn(app, @CommandValueSliderValueChanged, true);
            app.CommandValueSlider.Value = 0.5;
            app.WavelengthSliderLabel = uilabel(app.UIFigure);
            app.WavelengthSliderLabel.Text = 'Wavelength: ';
            app.WavelengthSlider = uislider(app.UIFigure);
            app.WavelengthSlider.Limits = [750 1080];
            app.WavelengthSlider.MajorTicks = [750 800 850 900 950 1000 1050];
            app.WavelengthSlider.ValueChangedFcn = createCallbackFcn(app, @WavelengthSliderValueChanged, true);
            app.WavelengthSlider.Value = 800;
            app.FilenameEditFieldLabel = uilabel(app.UIFigure);
            app.FilenameEditFieldLabel.Text = 'Filename';
            app.FilenameEditField = uieditfield(app.UIFigure, 'text');
            app.CommandVoltageLabel = uilabel(app.UIFigure);
            app.CommandVoltageLabel.Text = '0.5 V';
            app.WavelengthLabel = uilabel(app.UIFigure);
            app.WavelengthLabel.Text = '800 nm';
            app.PhotonsPerPulseLabel = uilabel(app.UIFigure);
            app.PhotonsPerPulseLabel.Text = '0';
            app.PhotonsPerPulseLabel_2 = uilabel(app.UIFigure);
            app.PhotonsPerPulseLabel_2.Text = 'Photons Per Pulse: ';
            app.PowerAtThisCommandLabel = uilabel(app.UIFigure);
            app.PowerAtThisCommandLabel.Text = '0 mW';
            app.PoweratthisCommandValueLabel = uilabel(app.UIFigure);
            app.PoweratthisCommandValueLabel.Text = 'Power at this Command Value:';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Clicky_GUI_For_Chrissi

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end